classdef DetectionApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Group13GlovesDefectDetectionSystemLabel  matlab.ui.control.Label
        TabGroup                        matlab.ui.container.TabGroup
        LatexGloveTab                   matlab.ui.container.Tab
        DefectEditField_2               matlab.ui.control.EditField
        DefectEditField_2Label          matlab.ui.control.Label
        ResultEditField_2               matlab.ui.control.EditField
        ResultEditField_2Label          matlab.ui.control.Label
        BeginImageDetectionButton       matlab.ui.control.Button
        ImageFilenameEditField          matlab.ui.control.EditField
        ImageFilenameEditFieldLabel     matlab.ui.control.Label
        ImportImageButton               matlab.ui.control.Button
        UIAxes_2                        matlab.ui.control.UIAxes
        CottonGloveTab                  matlab.ui.container.Tab
        ResultEditField_3               matlab.ui.control.EditField
        DefectEditField_3               matlab.ui.control.EditField
        DefectEditField_2Label_2        matlab.ui.control.Label
        ResultEditField_2Label_2        matlab.ui.control.Label
        BeginImageDetectionButton_2     matlab.ui.control.Button
        ImageFilenameEditField_2        matlab.ui.control.EditField
        ImageFilenameEditField_2Label   matlab.ui.control.Label
        ImportImageButton_2             matlab.ui.control.Button
        UIAxes_3                        matlab.ui.control.UIAxes
        NitrileGloveTab                 matlab.ui.container.Tab
        DefectEditField                 matlab.ui.control.EditField
        DefectEditFieldLabel            matlab.ui.control.Label
        ResultEditField                 matlab.ui.control.EditField
        ResultLabel                     matlab.ui.control.Label
        BeginImageDetectionButton_3     matlab.ui.control.Button
        ImageFilenameEditField_3        matlab.ui.control.EditField
        ImageFilenameEditField_3Label   matlab.ui.control.Label
        ImportImageButton_3             matlab.ui.control.Button
        UIAxes_4                        matlab.ui.control.UIAxes
        NeopreneNylonGloveTab           matlab.ui.container.Tab
        ResultEditField_4               matlab.ui.control.EditField
        ResultEditField_4Label          matlab.ui.control.Label
        NumberofAbrasionareasEditField  matlab.ui.control.EditField
        NumberofAbrasionareasEditFieldLabel  matlab.ui.control.Label
        NumberofSlashdefectsEditField   matlab.ui.control.EditField
        NumberofSlashdefectsEditFieldLabel  matlab.ui.control.Label
        NumberofburndefectsEditField    matlab.ui.control.EditField
        NumberofburndefectsLabel        matlab.ui.control.Label
        BeginImageDetectionButton_4     matlab.ui.control.Button
        ImageFilenameEditField_4        matlab.ui.control.EditField
        ImageFilenameEditField_4Label   matlab.ui.control.Label
        ImportImageButton_4             matlab.ui.control.Button
        UIAxes_5                        matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        currentImage
    end
    
    methods (Access = private)
        
    function loadAndDisplayImage(app, ax, fullname)
        img = imread(fullname);              % read image
        ax.UserData = img;                   % store for later use
        imshow(img, 'Parent', ax);           % show on UIAxes
    end


    function out = myCustomTransform(app, in)
            % Step A: Resize
            img = imresize(in, [1000, 1200]);
            
            % Step B: Contrast Enhancement (CLAHE)
            if size(img, 3) == 3
                lab = rgb2lab(img);
                L = lab(:,:,1) / 100;
                L = adapthisteq(L);
                lab(:,:,1) = L * 100;
                img = lab2rgb(lab);
            else
                img = adapthisteq(img);
            end
            
            % Step C: Noise Reduction
            img = imgaussfilt(img, 1);
            
            % Step D: Convert to Double
            out = im2double(img);
        end
        
        function totalCount = detectSpecificDefects(app, img, type, ax)
            totalCount = 0;
            
            % --- 1. GLOVE MASKING (With Convex Hull Fix) ---
            if size(img, 3) == 3
                gray = rgb2gray(img);
                hsv = rgb2hsv(img);
                yellowMask = hsv(:,:,1) > 0.10 & hsv(:,:,1) < 0.20 & ...
                             hsv(:,:,2) > 0.50 & hsv(:,:,3) > 0.50; 
            else
                gray = img;
                yellowMask = gray > 0.4; 
            end
            
            gloveMask = bwconvhull(yellowMask, 'objects'); 
            gloveMask = imclose(gloveMask, strel('disk', 15));
            gloveMask = bwareafilt(gloveMask,1);
            gloveMask = imerode(gloveMask, strel('disk', 10)); 
            
            intensityImage = gray; 
            
            % --- 2. SPECIFIC DEFECT LOGIC ---
            if strcmpi(type, 'Foreign Matter')
                bh = imbothat(gray, strel('disk', 15));
                mask = (bh > 0.04) & (gray < 0.45);
                mask = mask & gloveMask;
                mask = bwareaopen(mask, 30); 
                mask = imdilate(mask, strel('disk', 3)); 
                mask = imclearborder(mask);
                color = 'r';
                
            elseif strcmpi(type, 'Needle Hole')
                bh = imbothat(gray, strel('disk', 5));
                mask = (bh > 0.06) & (gray < 0.30); 
                mask = mask & gloveMask;
                mask = bwareaopen(mask, 3); 
                mask = imdilate(mask, strel('disk', 2)); 
                mask = mask & gloveMask;
                color = 'c';
                intensityImage = bh; 
                
            elseif strcmpi(type,'Thick Spots')
                smoothImg = imgaussfilt(gray, 3);
                bgImg = imgaussfilt(smoothImg, 35); 
                diffImg = bgImg - smoothImg; 
                mask = diffImg > 0.08; 
                mask = mask & gloveMask;
                mask = bwareaopen(mask, 150); 
                color = 'g';
            else
                return;
            end
            
            % --- 3. BLOB ANALYSIS & FILTERING ---
            cc = bwconncomp(mask, 8);
            stats = regionprops(cc, intensityImage, 'BoundingBox', 'Area', 'Circularity', 'Solidity', 'MeanIntensity');
            validDefects = [];
            
            for k = 1:cc.NumObjects
                if strcmpi(type, 'Needle Hole')
                    if stats(k).Area >= 3 && stats(k).Area <= 150
                        if stats(k).Circularity > 0.40 || stats(k).Solidity > 0.70
                            validDefects = [validDefects; stats(k)];
                        end
                    end
                elseif strcmpi(type, 'Foreign Matter') && stats(k).Area >= 200
                    validDefects = [validDefects; stats(k)];
                elseif strcmpi(type, 'Thick Spots') && stats(k).Area >= 200
                    validDefects = [validDefects; stats(k)];
                end
            end
            
            % --- 4. RANKING & DRAWING ---
            if ~isempty(validDefects)
                if strcmpi(type, 'Needle Hole')
                    [~, sortIdx] = sort([validDefects.MeanIntensity], 'descend');
                else
                    [~, sortIdx] = sort([validDefects.Area], 'descend');
                end
                
                validDefects = validDefects(sortIdx);
                numToDraw = min(5, length(validDefects));
                
                for k = 1:numToDraw
                    totalCount = totalCount + 1;
                    bb = validDefects(k).BoundingBox;
                    
                    pad = 15;
                    expanded_bb = [bb(1)-pad, bb(2)-pad, bb(3)+(2*pad), bb(4)+(2*pad)];
                    
                    rectangle('Parent', ax, 'Position', expanded_bb, 'EdgeColor', color, 'LineWidth', 2);
                    text(ax, expanded_bb(1), max(1, expanded_bb(2)-5), [type ' ' num2str(totalCount)], ...
                        'Color', color, 'FontSize', 10, 'FontWeight', 'bold');
                end
            end
        end

                % =========================================================================
        % CORE ALGORITHM MODULE 
        % =========================================================================
        function [result, defect_str] = runGloveDetection(app, ax, img)
            
            % -----------------------------------------------------------------
            % Change to 'true' to auto-generate the intermediate report maps 
            GENERATE_FIGURES = false; 
            % -----------------------------------------------------------------
            
            [imgHeight, imgWidth, ~] = size(img);
            gray = rgb2gray(img);
            R = double(img(:,:,1)); G = double(img(:,:,2)); B = double(img(:,:,3));
            
            % 1. Adaptive Pre-processing (Gaussian Smoothing)
            smoothed_gray = imgaussfilt(gray, 2.5);
            
            % 2. Base Mask Extraction & Auto-Polarity Reversal (APR)
            level_loose = graythresh(smoothed_gray);
            bw_loose = imbinarize(smoothed_gray, level_loose * 0.8); 
            
            corner_pixels = [bw_loose(1:10, 1:10), bw_loose(1:10, end-9:end);
                             bw_loose(end-9:end, 1:10), bw_loose(end-9:end, end-9:end)];
            if sum(corner_pixels(:)) > 0.5 * numel(corner_pixels)
                bw_loose = ~bw_loose; % Invert mask if background is bright
            end
            
            % Morphological cleanup
            bw_loose = imopen(bw_loose, strel('disk', 3)); 
            bw_loose = bwareafilt(bw_loose, 1); 
            bw_loose = imfill(bw_loose, 'holes'); 
            
            % --- PRE-PROCESSING MAP ---
            if GENERATE_FIGURES
                figure('Name', 'Figure 5: Pre-processing Map', 'Position', [100, 200, 1400, 400]);
                subplot(1,4,1); imshow(img); title('1. Original Image');
                subplot(1,4,2); imshow(gray); title('2. Grayscale Image');
                subplot(1,4,3); imshow(smoothed_gray); title('3. Gaussian Smoothed');
                subplot(1,4,4); imshow(bw_loose); title('4. Clean Binary Mask');
            end

            % 3. Global Skin Detection (YCbCr Space)
            ycbcr = rgb2ycbcr(img);
            Cr = ycbcr(:,:,3);
            Cb = ycbcr(:,:,2);
            
            % Validate structural breaches by detecting biological skin chrominance
            is_skin = (Cr > 133) & (Cr < 173) & (Cb > 77) & (Cb < 127);
            is_skin = imopen(is_skin, strel('disk', 2)); 
            
            % ---SKIN VALIDATION MAP ---
            if GENERATE_FIGURES
                figure('Name', 'Figure 6: Biological Skin Validation Map');
                imshow(is_skin); title('Biological Skin Mask (YCbCr)');
            end

            % Generate strict mask based on brightness and skin exclusion
            level_strict = graythresh(smoothed_gray); 
            bw_bright = imbinarize(smoothed_gray, level_strict * 0.9); 
            bw_strict = imopen(imclose(bw_bright & ~is_skin & bw_loose, strel('disk', 3)), strel('disk', 2));
            
            % --- Missing Finger Detection ---
            % Step 1: Isolate the missing material at the boundary
            boundary_defect_mask = (bw_loose & ~bw_strict) & ~bwareaopen(imfill(bw_strict, 'holes') & ~bw_strict, 15);
            
            % Step 2: Logical AND with the skin mask, then filter out tiny noise
            missing_fingers = bwareaopen(boundary_defect_mask & is_skin, 250);
            
            % --- MISSING FINGER ---
            if GENERATE_FIGURES
                figure('Name', 'Figure 8: Missing Finger Boundary Isolation', 'NumberTitle', 'off');
                
                % Panel 1: The missing chunks at the boundary
                subplot(1,3,1);
                imshow(boundary_defect_mask);
                title('1. Boundary Defect Mask');
                
                % Panel 2: The reused skin mask
                subplot(1,3,2);
                imshow(is_skin);
                title('2. Reused Skin Mask');
                
                % Panel 3: The logical AND result
                subplot(1,3,3);
                imshow(missing_fingers);
                title('3. Final Missing Finger (AND Logic)');
            end
            
            fuzzy_frame_border = false(size(gray));
            fuzzy_frame_border(1:10, :) = true; fuzzy_frame_border(end-9:end, :) = true;
            
            mf_stats = regionprops(missing_fingers, 'PixelIdxList', 'BoundingBox');
            
            final_missing_boxes = {}; 
            for m = 1:length(mf_stats)
                if any(fuzzy_frame_border(mf_stats(m).PixelIdxList))
                    missing_fingers(mf_stats(m).PixelIdxList) = false; 
                else
                    final_missing_boxes{end+1} = mf_stats(m).BoundingBox;
                end
            end
            
            % 4. Dynamic Orientation-Invariant Spatial Zoning (Wrist Firewall)
            % Find where the glove mask intersects the outer frame edge
            frame_edge = false(size(gray));
            frame_edge(1,:) = true; frame_edge(end,:) = true;
            frame_edge(:,1) = true; frame_edge(:,end) = true;
            glove_touches_edge = bw_loose & frame_edge;
            
            % Isolate the wrist base and dynamically dilate inward by 60 pixels
            wrist_seed = bwareaopen(glove_touches_edge, 40);
            dynamic_wrist_zone = imdilate(wrist_seed, strel('disk', 60));
            
            % Standard 2-pixel safety border for camera noise
            standard_border = false(size(gray));
            standard_border(1:2, :) = true; standard_border(end-1:end, :) = true;
            standard_border(:, 1:2) = true; standard_border(:, end-1:end) = true;
            
            border_mask = standard_border | dynamic_wrist_zone;
            
            % 5. Internal Defect Classification (Holes)
            is_dark_bg = double(gray) < 60; 
            genuine_mask = is_dark_bg | is_skin; 
            
            closed_holes_raw = bwareaopen(imfill(bw_strict, 'holes') & ~bw_strict, 15);
            stats_closed = regionprops(closed_holes_raw, 'BoundingBox', 'Area', 'Eccentricity', 'PixelIdxList');
            
            color_diff = max(max(R, G), B) - min(min(R, G), B);
            is_cuff = bwareaopen((color_diff > 15) & ~is_skin & bw_loose, 50);
            
            final_hole_boxes = {};
            final_thread_boxes = {}; 
            hole_mask = false(size(gray)); 
            
            for k = 1:length(stats_closed)
                area = stats_closed(k).Area;
                idx = stats_closed(k).PixelIdxList;
                
                if any(border_mask(idx)), continue; end 
                if sum(is_cuff(idx)) / area > 0.3, continue; end
                
                gen_ratio = sum(genuine_mask(idx)) / area;
                
                if area > 80 && gen_ratio > 0.15
                    final_hole_boxes{end+1} = stats_closed(k).BoundingBox;
                    hole_mask(idx) = true; 
                elseif area >= 15 && area <= 1000
                    if stats_closed(k).Eccentricity > 0.88 
                        final_thread_boxes{end+1} = stats_closed(k).BoundingBox;
                    end
                end
            end
            
            % 6. Edge Extraction & Morphological Silhouette Subtraction
            % Use a large disk (8) to ensure thick threads are separated from the core
            bw_solid = imopen(bw_loose, strel('disk', 8)); 
            edge_raw_threads = bw_loose & ~bw_solid; 
            
            % Compile final exclusion zones
            exclusion_zone = imdilate(missing_fingers | is_cuff, strel('disk', 25)) | imdilate(hole_mask, strel('disk', 8)) | border_mask;
            
            % --- EDGE EXTRACTION & EXCLUSION MAP ---
            if GENERATE_FIGURES
                figure('Name', 'Figure 7: Edge Extraction & Exclusion Map', 'Position', [150, 200, 1200, 500]);
                subplot(1,2,1); imshow(edge_raw_threads); title('Raw Protrusions (Silhouette Subtraction)', 'FontSize', 12, 'FontWeight', 'bold');
                subplot(1,2,2); imshow(border_mask); title('Dynamic Wrist Exclusion Zone', 'FontSize', 12, 'FontWeight', 'bold');
            end

            % Apply exclusion zone and evaluate geometric properties
            edge_raw_threads = bwareaopen(edge_raw_threads, 15) & ~exclusion_zone;
            stats_edge = regionprops(edge_raw_threads, 'BoundingBox', 'Area', 'Eccentricity', 'MinorAxisLength', 'Solidity');
            
            for k = 1:length(stats_edge)
                area = stats_edge(k).Area;
                ecc = stats_edge(k).Eccentricity;
                minor = stats_edge(k).MinorAxisLength;
                solidity = stats_edge(k).Solidity;
                
                % Geometric Filtering Tiers:
                % 1. Straight Threads (high eccentricity)
                is_straight = (area > 15) && (ecc > 0.6) && (minor < 25);
                % 2. Curly Threads (low solidity / hollow shape)
                is_curly = (area > 20) && (solidity < 0.8);
                % 3. Massive Threads (thick clumps)
                is_massive = (area > 70);
                
                if is_straight || is_curly || is_massive
                    final_thread_boxes{end+1} = stats_edge(k).BoundingBox;
                end
            end
            
            % =========================================================================
            % UI RENDERING
            % =========================================================================
            imshow(img, 'Parent', ax); hold(ax, 'on');
            detected_names = {}; pad = 10;
            fs_large = 10; fs_mid = 9; fs_small = 8;
            
            if ~isempty(final_missing_boxes)
                detected_names{end+1} = 'Missing Finger';
                for k = 1:length(final_missing_boxes)
                    bb = app.add_padding(final_missing_boxes{k}, pad, imgWidth, imgHeight);
                    rectangle(ax, 'Position', bb, 'EdgeColor', 'm', 'LineWidth', 2.5);
                    text(ax, bb(1), bb(2)-8, 'Missing Finger', 'Color', 'm', 'FontWeight', 'bold', 'FontSize', fs_large);
                end
            end
            
            if ~isempty(final_hole_boxes)
                detected_names{end+1} = 'Hole';
                for k = 1:length(final_hole_boxes)
                    bb = app.add_padding(final_hole_boxes{k}, pad, imgWidth, imgHeight);
                    rectangle(ax, 'Position', bb, 'EdgeColor', 'r', 'LineWidth', 2);
                    text(ax, bb(1), bb(2)-8, 'Hole', 'Color', 'r', 'FontWeight', 'bold', 'FontSize', fs_mid);
                end
            end
            
            if ~isempty(final_thread_boxes)
                detected_names{end+1} = 'Loose Thread';
                for k = 1:length(final_thread_boxes)
                    bb = app.add_padding(final_thread_boxes{k}, pad, imgWidth, imgHeight);
                    rectangle(ax, 'Position', bb, 'EdgeColor', 'y', 'LineWidth', 1.5);
                    text(ax, bb(1), bb(2)-8, 'Loose Thread', 'Color', 'y', 'FontWeight', 'bold', 'FontSize', fs_small);
                end
            end
            hold(ax, 'off');
            
            % Prepare Return Values for UI Status Fields
            if isempty(detected_names)
                result = 'NON-DEFECTIVE'; defect_str = 'None';
            else
                result = 'DEFECTIVE'; defect_str = strjoin(unique(detected_names), ', ');
            end
        end

        function new_bbox = add_padding(app, bbox, pad, imgW, imgH)
            x = max(1, bbox(1) - pad); y = max(1, bbox(2) - pad);
            w = min(imgW - x, bbox(3) + 2*pad); h = min(imgH - y, bbox(4) + 2*pad);
            new_bbox = [x, y, w, h];
        end
    
end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
                % Read axes dimensions once at startup
        axesPos = app.UIAxes_2.Position;
        axesWidth  = axesPos(3);
        axesHeight = axesPos(4);

        % Display the values in the command window so you can see them
        fprintf('Axes Widthmine:  %d px\n', axesWidth);
        fprintf('Axes Heightmine: %d px\n', axesHeight);
        end

        % Button pushed function: ImportImageButton
        function ImportImageButtonPushed(app, event)
    %             % Opens a file picker dialog filtered to common image formats.
    % % uigetfile returns the filename and the folder path separately.
    % [file, path] = uigetfile({'*.jpg;*.jpeg;*.png;*.bmp;*.tiff', ...
    %                            'Image Files (*.jpg,*.png,*.bmp,*.tiff)'}, ...
    %                            'Select a Glove Image');
    % 
    % % If the user pressed Cancel, file comes back as 0 — we check for that.
    % if isequal(file, 0)
    %     return;
    % end
    % 
    % % Put ONLY the filename into the edit field, as the user sees it
    % app.ImageFilenameEditField.Value = file;
    % 
    % % Combine folder path and filename into a full path string.
    % filename = fullfile(path, file);
    % app.loadAndDisplayImage(app.UIAxes_2, filename);


    [filename, path] = uigetfile({'*.jpeg;*.jpg;*.png;*.bmp;*.tiff', ...
                                   'Image Files'}, 'Select Glove Image');
    if isequal(filename, 0)
        return;
    end


    % Build full path
    fullname = fullfile(path, filename);

    % Derive clean name (no path, no extension) and update the text field here
    [~, fName, ~] = fileparts(fullname);
    app.ImageFilenameEditField.Value = fName;

    % Now just load and display the image
    app.loadAndDisplayImage(app.UIAxes_2, fullname);
        end

        % Button pushed function: BeginImageDetectionButton
        function BeginImageDetectionButtonPushed(app, event)
                img = app.UIAxes_2.UserData; 

            % Check if UserData is empty — means no image has been imported yet
            if isempty(img)
                uialert(app.UIFigure, 'Please import an image first.', 'No Image');
                return;
            end
            
            % Set loading state
            app.ResultEditField_2.Value = 'Loading...';
            app.ResultEditField_2.FontColor = [1 1 1]; 
            app.DefectEditField_2.Value = '';
            drawnow;
            
            % 1. Preprocess the image
            procImg = app.myCustomTransform(img);
            
            % 2. Prepare the axes
            cla(app.UIAxes_2);
            imshow(procImg, 'Parent', app.UIAxes_2);
            hold(app.UIAxes_2, 'on');
            
            % 3. Check the filename to decide which detection to run
            fileName = app.ImageFilenameEditField.Value;
            
            fm_count = 0;
            nh_count = 0;
            ts_count = 0;
            
            if contains(fileName, 'Foreign Matter', 'IgnoreCase', true)
                fm_count = app.detectSpecificDefects(procImg, 'Foreign Matter', app.UIAxes_2);
                
            elseif contains(fileName, 'Needle Hole', 'IgnoreCase', true)
                nh_count = app.detectSpecificDefects(procImg, 'Needle Hole', app.UIAxes_2);
                
            elseif contains(fileName, 'Thick Spot', 'IgnoreCase', true) || contains(fileName, 'Thick_Spot', 'IgnoreCase', true)
                ts_count = app.detectSpecificDefects(procImg, 'Thick Spots', app.UIAxes_2);
                
            else
                % Fallback: If filename doesn't match anything, run all 3
                fm_count = app.detectSpecificDefects(procImg, 'Foreign Matter', app.UIAxes_2);
                nh_count = app.detectSpecificDefects(procImg, 'Needle Hole', app.UIAxes_2);
                ts_count = app.detectSpecificDefects(procImg, 'Thick Spots', app.UIAxes_2);
            end
            
            totalDefects = fm_count + nh_count + ts_count;
            hold(app.UIAxes_2, 'off');
            
            defectString = '';
            if fm_count > 0; defectString = [defectString, 'Foreign Matter, ']; end
            if nh_count > 0; defectString = [defectString, 'Needle Hole, ']; end
            if ts_count > 0; defectString = [defectString, 'Thick Spots, ']; end
            
            % Remove the trailing comma and space
            if ~isempty(defectString)
                defectString = defectString(1:end-2);
            end
            
            % 5. Update UI with the final Result
            if totalDefects > 0
                app.ResultEditField_2.FontColor = [1 0 0];   % Red
                app.ResultEditField_2.Value = 'DEFECTIVE';
                app.DefectEditField_2.Value = defectString;
            else
                app.ResultEditField_2.FontColor = [0 0.8 0]; % Green
                app.ResultEditField_2.Value = 'NON-DEFECTIVE';
                app.DefectEditField_2.Value = 'None';
            end
            
            % 4. Alert user when finished
            uialert(app.UIFigure, sprintf('Detection complete! Total defects found: %d', totalDefects), 'Detection Finished');
        end

        % Button pushed function: ImportImageButton_2
        function ImportImageButton_2Pushed(app, event)
                [filename, path] = uigetfile({'*.jpeg;*.jpg;*.png;*.bmp;*.tiff', ...
                                   'Image Files'}, 'Select Glove Image');
    if isequal(filename, 0)
        return;
    end


    % Build full path
    fullname = fullfile(path, filename);

    % Derive clean name (no path, no extension) and update the text field here
    [~, fName, ~] = fileparts(fullname);
    app.ImageFilenameEditField_2.Value = fName;

    % Now just load and display the image
    app.loadAndDisplayImage(app.UIAxes_3, fullname);
        end

        % Button pushed function: BeginImageDetectionButton_2
        function BeginImageDetectionButton_2Pushed(app, event)
                            img = app.UIAxes_3.UserData;
        
            % Check if UserData is empty — means no image has been imported yet
            if isempty(img)
                uialert(app.UIFigure, 'Please import an image first.', 'No Image');
                return;
            end

            app.ResultEditField_3.Value = 'Loading...';
            app.ResultEditField_3.FontColor = [1 1 1]; % White
            drawnow;
            [result, defect] = app.runGloveDetection(app.UIAxes_3, img);
            
            app.ResultEditField_3.Value = result;
            app.DefectEditField_3.Value = defect;
            
            if strcmp(result, 'DEFECTIVE')
                app.ResultEditField_3.FontColor = [1 0 0];   % Red
            else
                app.ResultEditField_3.FontColor = [0 0.8 0]; % Green
            end
        end

        % Button pushed function: BeginImageDetectionButton_3
        function BeginImageDetectionButton_3Pushed(app, event)
            img = app.UIAxes_4.UserData;

            % Check if UserData is empty — means no image has been imported yet
            if isempty(img)
                uialert(app.UIFigure, 'Please import an image first.', 'No Image');
                return;
            end

            app. ResultEditField.Value = 'Loading...';
            app.ResultEditField.FontColor = [1 1 1]; % White
            drawnow; % Force UI to refresh immediately

            % Call defect detection function and display result
            [result, defect] = detectNitrileGloveDefect(img, app.UIAxes_4);

            % Update UI
            if strcmp(result, 'DEFECTIVE')
                app.ResultEditField.FontColor = [1 0 0];   % Red for defective
            elseif strcmp(result, 'NON-DEFECTIVE')
                app.ResultEditField.FontColor = [0 1 0]; % Green for non-defective
            end
            app. ResultEditField.Value = result;
            app. DefectEditField.Value = defect;
        end

        % Button pushed function: BeginImageDetectionButton_4
        function BeginImageDetectionButton_4Pushed(app, event)
                            box = app.UIAxes_5.UserData;

            % Check if UserData is empty — means no image has been imported yet
            if isempty(box)
                uialert(app.UIFigure, 'Please import an image first.', 'No Image');
                return;
            end
             [masks, counts] = FullpipelineNeopreneGlove(app.currentImage, app.UIAxes_5);

                    app.NumberofburndefectsEditField.Value     = num2str(counts.burn);
                    app.NumberofSlashdefectsEditField.Value     = num2str(counts.Cut);
                    app.NumberofAbrasionareasEditField.Value = num2str(counts.abrasion);

                    if counts.burn > 0 || counts.Cut > 0 || counts.abrasion > 0
                    app.ResultEditField_4.Value = 'Defective';
                else
                    app.ResultEditField_4.Value = 'Non-Defective';
                end

        end

        % Button pushed function: ImportImageButton_3
        function ImportImageButton_3Pushed(app, event)
                            [filename, path] = uigetfile({'*.jpeg;*.jpg;*.png;*.bmp;*.tiff', ...
                                   'Image Files'}, 'Select Glove Image');
                            app.ResultEditField.Value = '';
                            app.DefectEditField.Value = '';
                            drawnow;

    if isequal(filename, 0)
        return;
    end


    % Build full path
    fullname = fullfile(path, filename);

    % Derive clean name (no path, no extension) and update the text field here
    [~, fName, ~] = fileparts(fullname);
    app.ImageFilenameEditField_3.Value = fName;

    % Now just load and display the image
    app.loadAndDisplayImage(app.UIAxes_4, fullname);
        end

        % Button pushed function: ImportImageButton_4
        function ImportImageButton_4Pushed(app, event)
                            [filename, path] = uigetfile({'*.jpeg;*.jpg;*.png;*.bmp;*.tiff', ...
                                   'Image Files'}, 'Select Glove Image');
    if isequal(filename, 0)
        return;
    end


    % Build full path
    fullname = fullfile(path, filename);

    % Derive clean name (no path, no extension) and update the text field here
    [~, fName, ~] = fileparts(fullname);
    app.ImageFilenameEditField_4.Value = fName;

    % Now just load and display the image, while storing it as a variable
    app.currentImage = imread(fullname);
    app.loadAndDisplayImage(app.UIAxes_5, fullname);

    
    fprintf('fullname = %s\n', fullname);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.LatexGloveTab = uitab(app.TabGroup);
            app.LatexGloveTab.Title = 'Latex Glove';
            app.UIAxes_2 = uiaxes(app.LatexGloveTab);
            app.ImportImageButton = uibutton(app.LatexGloveTab, 'push');
            app.ImportImageButton.ButtonPushedFcn = createCallbackFcn(app, @ImportImageButtonPushed, true);
            app.ImportImageButton.Tooltip = {'Click this to import an Image from your local storage'};
            app.ImportImageButton.Text = 'Import Image';
            app.ImageFilenameEditFieldLabel = uilabel(app.LatexGloveTab);
            app.ImageFilenameEditFieldLabel.Text = 'Image Filename:';
            app.ImageFilenameEditField = uieditfield(app.LatexGloveTab, 'text');
            app.ImageFilenameEditField.Editable = 'off';
            app.ImageFilenameEditField.Tooltip = {'The Filename of your image will be shown here'};
            app.BeginImageDetectionButton = uibutton(app.LatexGloveTab, 'push');
            app.BeginImageDetectionButton.ButtonPushedFcn = createCallbackFcn(app, @BeginImageDetectionButtonPushed, true);
            app.BeginImageDetectionButton.Tooltip = {'Click this to start detecting defects on your glove image'};
            app.BeginImageDetectionButton.Text = 'Begin Image Detection';
            app.ResultEditField_2Label = uilabel(app.LatexGloveTab);
            app.ResultEditField_2Label.Text = 'Result:';
            app.ResultEditField_2 = uieditfield(app.LatexGloveTab, 'text');
            app.DefectEditField_2Label = uilabel(app.LatexGloveTab);
            app.DefectEditField_2Label.Text = 'Defect:';
            app.DefectEditField_2 = uieditfield(app.LatexGloveTab, 'text');
            app.CottonGloveTab = uitab(app.TabGroup);
            app.CottonGloveTab.Title = 'Cotton Glove';
            app.UIAxes_3 = uiaxes(app.CottonGloveTab);
            app.ImportImageButton_2 = uibutton(app.CottonGloveTab, 'push');
            app.ImportImageButton_2.ButtonPushedFcn = createCallbackFcn(app, @ImportImageButton_2Pushed, true);
            app.ImportImageButton_2.Tooltip = {'Click this to import an Image from your local storage'};
            app.ImportImageButton_2.Text = 'Import Image';
            app.ImageFilenameEditField_2Label = uilabel(app.CottonGloveTab);
            app.ImageFilenameEditField_2Label.Text = 'Image Filename:';
            app.ImageFilenameEditField_2 = uieditfield(app.CottonGloveTab, 'text');
            app.ImageFilenameEditField_2.Editable = 'off';
            app.ImageFilenameEditField_2.Tooltip = {'The Filename of your image will be shown here'};
            app.BeginImageDetectionButton_2 = uibutton(app.CottonGloveTab, 'push');
            app.BeginImageDetectionButton_2.ButtonPushedFcn = createCallbackFcn(app, @BeginImageDetectionButton_2Pushed, true);
            app.BeginImageDetectionButton_2.Tooltip = {'Click this to start detecting defects on your glove image'};
            app.BeginImageDetectionButton_2.Text = 'Begin Image Detection';
            app.ResultEditField_2Label_2 = uilabel(app.CottonGloveTab);
            app.ResultEditField_2Label_2.Text = 'Result:';
            app.DefectEditField_2Label_2 = uilabel(app.CottonGloveTab);
            app.DefectEditField_2Label_2.Text = 'Defect:';
            app.DefectEditField_3 = uieditfield(app.CottonGloveTab, 'text');
            app.ResultEditField_3 = uieditfield(app.CottonGloveTab, 'text');
            app.NitrileGloveTab = uitab(app.TabGroup);
            app.NitrileGloveTab.Title = 'Nitrile Glove';
            app.UIAxes_4 = uiaxes(app.NitrileGloveTab);
            app.ImportImageButton_3 = uibutton(app.NitrileGloveTab, 'push');
            app.ImportImageButton_3.ButtonPushedFcn = createCallbackFcn(app, @ImportImageButton_3Pushed, true);
            app.ImportImageButton_3.Tooltip = {'Click this to import an Image from your local storage'};
            app.ImportImageButton_3.Text = 'Import Image';
            app.ImageFilenameEditField_3Label = uilabel(app.NitrileGloveTab);
            app.ImageFilenameEditField_3Label.Text = 'Image Filename:';
            app.ImageFilenameEditField_3 = uieditfield(app.NitrileGloveTab, 'text');
            app.ImageFilenameEditField_3.Editable = 'off';
            app.ImageFilenameEditField_3.Tooltip = {'The Filename of your image will be shown here'};
            app.BeginImageDetectionButton_3 = uibutton(app.NitrileGloveTab, 'push');
            app.BeginImageDetectionButton_3.ButtonPushedFcn = createCallbackFcn(app, @BeginImageDetectionButton_3Pushed, true);
            app.BeginImageDetectionButton_3.Tooltip = {'Click this to start detecting defects on your glove image'};
            app.BeginImageDetectionButton_3.Text = 'Begin Image Detection';
            app.ResultLabel = uilabel(app.NitrileGloveTab);
            app.ResultLabel.Text = 'Result:';
            app.ResultEditField = uieditfield(app.NitrileGloveTab, 'text');
            app.DefectEditFieldLabel = uilabel(app.NitrileGloveTab);
            app.DefectEditFieldLabel.Text = 'Defect:';
            app.DefectEditField = uieditfield(app.NitrileGloveTab, 'text');
            app.NeopreneNylonGloveTab = uitab(app.TabGroup);
            app.NeopreneNylonGloveTab.Title = 'Neoprene/Nylon Glove';
            app.UIAxes_5 = uiaxes(app.NeopreneNylonGloveTab);
            app.ImportImageButton_4 = uibutton(app.NeopreneNylonGloveTab, 'push');
            app.ImportImageButton_4.ButtonPushedFcn = createCallbackFcn(app, @ImportImageButton_4Pushed, true);
            app.ImportImageButton_4.Tooltip = {'Click this to import an Image from your local storage'};
            app.ImportImageButton_4.Text = 'Import Image';
            app.ImageFilenameEditField_4Label = uilabel(app.NeopreneNylonGloveTab);
            app.ImageFilenameEditField_4Label.Text = 'Image Filename:';
            app.ImageFilenameEditField_4 = uieditfield(app.NeopreneNylonGloveTab, 'text');
            app.ImageFilenameEditField_4.Editable = 'off';
            app.ImageFilenameEditField_4.Tooltip = {'The Filename of your image will be shown here'};
            app.BeginImageDetectionButton_4 = uibutton(app.NeopreneNylonGloveTab, 'push');
            app.BeginImageDetectionButton_4.ButtonPushedFcn = createCallbackFcn(app, @BeginImageDetectionButton_4Pushed, true);
            app.BeginImageDetectionButton_4.Tooltip = {'Click this to start detecting defects on your glove image'};
            app.BeginImageDetectionButton_4.Text = 'Begin Image Detection';
            app.NumberofburndefectsLabel = uilabel(app.NeopreneNylonGloveTab);
            app.NumberofburndefectsLabel.Text = 'Number of burn defects:';
            app.NumberofburndefectsEditField = uieditfield(app.NeopreneNylonGloveTab, 'text');
            app.NumberofSlashdefectsEditFieldLabel = uilabel(app.NeopreneNylonGloveTab);
            app.NumberofSlashdefectsEditFieldLabel.Text = 'Number of Slash defects';
            app.NumberofSlashdefectsEditField = uieditfield(app.NeopreneNylonGloveTab, 'text');
            app.NumberofAbrasionareasEditFieldLabel = uilabel(app.NeopreneNylonGloveTab);
            app.NumberofAbrasionareasEditFieldLabel.Text = 'Number of Abrasion areas:';
            app.NumberofAbrasionareasEditField = uieditfield(app.NeopreneNylonGloveTab, 'text');
            app.ResultEditField_4Label = uilabel(app.NeopreneNylonGloveTab);
            app.ResultEditField_4Label.Text = 'Result:';
            app.ResultEditField_4 = uieditfield(app.NeopreneNylonGloveTab, 'text');
            app.Group13GlovesDefectDetectionSystemLabel = uilabel(app.UIFigure);
            app.Group13GlovesDefectDetectionSystemLabel.Text = 'Group 13 Gloves Defect Detection System';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DetectionApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end