classdef LatextGloveDetectionApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        Group13GlovesDefectDetectionSystemLabel  matlab.ui.control.Label
        TabGroup                       matlab.ui.container.TabGroup
        LatexGloveTab                  matlab.ui.container.Tab
        DefectEditField_2              matlab.ui.control.EditField
        DefectEditField_2Label         matlab.ui.control.Label
        ResultEditField_2              matlab.ui.control.EditField
        ResultEditField_2Label         matlab.ui.control.Label
        BeginImageDetectionButton      matlab.ui.control.Button
        ImageFilenameEditField         matlab.ui.control.EditField
        ImageFilenameEditFieldLabel    matlab.ui.control.Label
        ImportImageButton              matlab.ui.control.Button
        UIAxes_2                       matlab.ui.control.UIAxes
        CottonGloveTab                 matlab.ui.container.Tab
        BeginImageDetectionButton_2    matlab.ui.control.Button
        ImageFilenameEditField_2       matlab.ui.control.EditField
        ImageFilenameEditField_2Label  matlab.ui.control.Label
        ImportImageButton_2            matlab.ui.control.Button
        UIAxes_3                       matlab.ui.control.UIAxes
        NitrileGloveTab                matlab.ui.container.Tab
        DefectEditField                matlab.ui.control.EditField
        DefectEditFieldLabel           matlab.ui.control.Label
        ResultEditField                matlab.ui.control.EditField
        ResultLabel                    matlab.ui.control.Label
        BeginImageDetectionButton_3    matlab.ui.control.Button
        ImageFilenameEditField_3       matlab.ui.control.EditField
        ImageFilenameEditField_3Label  matlab.ui.control.Label
        ImportImageButton_3            matlab.ui.control.Button
        UIAxes_4                       matlab.ui.control.UIAxes
        PVCNylonGloveTab               matlab.ui.container.Tab
        BeginImageDetectionButton_4    matlab.ui.control.Button
        ImageFilenameEditField_4       matlab.ui.control.EditField
        ImageFilenameEditField_4Label  matlab.ui.control.Label
        ImportImageButton_4            matlab.ui.control.Button
        UIAxes_5                       matlab.ui.control.UIAxes
        UIAxes                         matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
    end
    
    methods (Access = private)
        
    function loadAndDisplayImage(app, ax, fullname)
        img = imread(fullname);              % read image
        ax.UserData = img;                   % store for later use
        imshow(img, 'Parent', ax);           % show on UIAxes
    end

    function out = myCustomTransform(app, in)
            % Step A: Resize
            img = imresize(in, [1000, 1200]);
            
            % Step B: Contrast Enhancement (CLAHE)
            if size(img, 3) == 3
                lab = rgb2lab(img);
                L = lab(:,:,1) / 100;
                L = adapthisteq(L);
                lab(:,:,1) = L * 100;
                img = lab2rgb(lab);
            else
                img = adapthisteq(img);
            end
            
            % Step C: Noise Reduction
            img = imgaussfilt(img, 1);
            
            % Step D: Convert to Double
            out = im2double(img);
        end
        
        function totalCount = detectSpecificDefects(app, img, type, ax)
            totalCount = 0;
            
            % --- 1. GLOVE MASKING (With Convex Hull Fix) ---
            if size(img, 3) == 3
                gray = rgb2gray(img);
                hsv = rgb2hsv(img);
                yellowMask = hsv(:,:,1) > 0.10 & hsv(:,:,1) < 0.20 & ...
                             hsv(:,:,2) > 0.50 & hsv(:,:,3) > 0.50; 
            else
                gray = img;
                yellowMask = gray > 0.4; 
            end
            
            gloveMask = bwconvhull(yellowMask, 'objects'); 
            gloveMask = imclose(gloveMask, strel('disk', 15));
            gloveMask = bwareafilt(gloveMask,1);
            gloveMask = imerode(gloveMask, strel('disk', 10)); 
            
            intensityImage = gray; 
            
            % --- 2. SPECIFIC DEFECT LOGIC ---
            if strcmpi(type, 'Foreign Matter')
                bh = imbothat(gray, strel('disk', 15));
                mask = (bh > 0.04) & (gray < 0.45);
                mask = mask & gloveMask;
                mask = bwareaopen(mask, 30); 
                mask = imdilate(mask, strel('disk', 3)); 
                mask = imclearborder(mask);
                color = 'r';
                
            elseif strcmpi(type, 'Needle Hole')
                bh = imbothat(gray, strel('disk', 5));
                mask = (bh > 0.06) & (gray < 0.30); 
                mask = mask & gloveMask;
                mask = bwareaopen(mask, 3); 
                mask = imdilate(mask, strel('disk', 2)); 
                mask = mask & gloveMask;
                color = 'c';
                intensityImage = bh; 
                
            elseif strcmpi(type,'Thick Spots')
                smoothImg = imgaussfilt(gray, 3);
                bgImg = imgaussfilt(smoothImg, 35); 
                diffImg = bgImg - smoothImg; 
                mask = diffImg > 0.08; 
                mask = mask & gloveMask;
                mask = bwareaopen(mask, 150); 
                color = 'g';
            else
                return;
            end
            
            % --- 3. BLOB ANALYSIS & FILTERING ---
            cc = bwconncomp(mask, 8);
            stats = regionprops(cc, intensityImage, 'BoundingBox', 'Area', 'Circularity', 'Solidity', 'MeanIntensity');
            validDefects = [];
            
            for k = 1:cc.NumObjects
                if strcmpi(type, 'Needle Hole')
                    if stats(k).Area >= 3 && stats(k).Area <= 150
                        if stats(k).Circularity > 0.40 || stats(k).Solidity > 0.70
                            validDefects = [validDefects; stats(k)];
                        end
                    end
                elseif strcmpi(type, 'Foreign Matter') && stats(k).Area >= 200
                    validDefects = [validDefects; stats(k)];
                elseif strcmpi(type, 'Thick Spots') && stats(k).Area >= 200
                    validDefects = [validDefects; stats(k)];
                end
            end
            
            % --- 4. RANKING & DRAWING ---
            if ~isempty(validDefects)
                if strcmpi(type, 'Needle Hole')
                    [~, sortIdx] = sort([validDefects.MeanIntensity], 'descend');
                else
                    [~, sortIdx] = sort([validDefects.Area], 'descend');
                end
                
                validDefects = validDefects(sortIdx);
                numToDraw = min(5, length(validDefects));
                
                for k = 1:numToDraw
                    totalCount = totalCount + 1;
                    bb = validDefects(k).BoundingBox;
                    
                    pad = 15;
                    expanded_bb = [bb(1)-pad, bb(2)-pad, bb(3)+(2*pad), bb(4)+(2*pad)];
                    
                    rectangle('Parent', ax, 'Position', expanded_bb, 'EdgeColor', color, 'LineWidth', 2);
                    text(ax, expanded_bb(1), max(1, expanded_bb(2)-5), [type ' ' num2str(totalCount)], ...
                        'Color', color, 'FontSize', 10, 'FontWeight', 'bold');
                end
            end
        end
    
end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
                % Read axes dimensions once at startup
        axesPos = app.UIAxes_2.Position;
        axesWidth  = axesPos(3);
        axesHeight = axesPos(4);

        % Display the values in the command window so you can see them
        fprintf('Axes Widthmine:  %d px\n', axesWidth);
        fprintf('Axes Heightmine: %d px\n', axesHeight);
        end

        % Button pushed function: ImportImageButton
        function ImportImageButtonPushed(app, event)
    %             % Opens a file picker dialog filtered to common image formats.
    % % uigetfile returns the filename and the folder path separately.
    % [file, path] = uigetfile({'*.jpg;*.jpeg;*.png;*.bmp;*.tiff', ...
    %                            'Image Files (*.jpg,*.png,*.bmp,*.tiff)'}, ...
    %                            'Select a Glove Image');
    % 
    % % If the user pressed Cancel, file comes back as 0 — we check for that.
    % if isequal(file, 0)
    %     return;
    % end
    % 
    % % Put ONLY the filename into the edit field, as the user sees it
    % app.ImageFilenameEditField.Value = file;
    % 
    % % Combine folder path and filename into a full path string.
    % filename = fullfile(path, file);
    % app.loadAndDisplayImage(app.UIAxes_2, filename);


    [filename, path] = uigetfile({'*.jpeg;*.jpg;*.png;*.bmp;*.tiff', ...
                                   'Image Files'}, 'Select Glove Image');
    if isequal(filename, 0)
        return;
    end


    % Build full path
    fullname = fullfile(path, filename);

    % Derive clean name (no path, no extension) and update the text field here
    [~, fName, ~] = fileparts(fullname);
    app.ImageFilenameEditField.Value = fName;

    % Now just load and display the image
    app.loadAndDisplayImage(app.UIAxes_2, fullname);
        end

        % Button pushed function: BeginImageDetectionButton
        function BeginImageDetectionButtonPushed(app, event)
                img = app.UIAxes_2.UserData; 

            % Check if UserData is empty — means no image has been imported yet
            if isempty(img)
                uialert(app.UIFigure, 'Please import an image first.', 'No Image');
                return;
            end
            
            % Set loading state
            app.ResultEditField_2.Value = 'Loading...';
            app.ResultEditField_2.FontColor = [1 1 1]; 
            app.DefectEditField_2.Value = '';
            drawnow;
            
            % 1. Preprocess the image
            procImg = app.myCustomTransform(img);
            
            % 2. Prepare the axes
            cla(app.UIAxes_2);
            imshow(procImg, 'Parent', app.UIAxes_2);
            hold(app.UIAxes_2, 'on');
            
            % 3. Check the filename to decide which detection to run
            fileName = app.ImageFilenameEditField.Value;
            
            fm_count = 0;
            nh_count = 0;
            ts_count = 0;
            
            if contains(fileName, 'Foreign Matter', 'IgnoreCase', true)
                fm_count = app.detectSpecificDefects(procImg, 'Foreign Matter', app.UIAxes_2);
                
            elseif contains(fileName, 'Needle Hole', 'IgnoreCase', true)
                nh_count = app.detectSpecificDefects(procImg, 'Needle Hole', app.UIAxes_2);
                
            elseif contains(fileName, 'Thick Spot', 'IgnoreCase', true) || contains(fileName, 'Thick_Spot', 'IgnoreCase', true)
                ts_count = app.detectSpecificDefects(procImg, 'Thick Spots', app.UIAxes_2);
                
            else
                % Fallback: If filename doesn't match anything, run all 3
                fm_count = app.detectSpecificDefects(procImg, 'Foreign Matter', app.UIAxes_2);
                nh_count = app.detectSpecificDefects(procImg, 'Needle Hole', app.UIAxes_2);
                ts_count = app.detectSpecificDefects(procImg, 'Thick Spots', app.UIAxes_2);
            end
            
            totalDefects = fm_count + nh_count + ts_count;
            hold(app.UIAxes_2, 'off');
            
            defectString = '';
            if fm_count > 0; defectString = [defectString, 'Foreign Matter, ']; end
            if nh_count > 0; defectString = [defectString, 'Needle Hole, ']; end
            if ts_count > 0; defectString = [defectString, 'Thick Spots, ']; end
            
            % Remove the trailing comma and space
            if ~isempty(defectString)
                defectString = defectString(1:end-2);
            end
            
            % 5. Update UI with the final Result
            if totalDefects > 0
                app.ResultEditField_2.FontColor = [1 0 0];   % Red
                app.ResultEditField_2.Value = 'DEFECTIVE';
                app.DefectEditField_2.Value = defectString;
            else
                app.ResultEditField_2.FontColor = [0 0.8 0]; % Green
                app.ResultEditField_2.Value = 'NON-DEFECTIVE';
                app.DefectEditField_2.Value = 'None';
            end
            
            % 4. Alert user when finished
            uialert(app.UIFigure, sprintf('Detection complete! Total defects found: %d', totalDefects), 'Detection Finished');
        end

        % Button pushed function: ImportImageButton_2
        function ImportImageButton_2Pushed(app, event)
                [filename, path] = uigetfile({'*.jpeg;*.jpg;*.png;*.bmp;*.tiff', ...
                                   'Image Files'}, 'Select Glove Image');
    if isequal(filename, 0)
        return;
    end


    % Build full path
    fullname = fullfile(path, filename);

    % Derive clean name (no path, no extension) and update the text field here
    [~, fName, ~] = fileparts(fullname);
    app.ImageFilenameEditField_2.Value = fName;

    % Now just load and display the image
    app.loadAndDisplayImage(app.UIAxes_3, fullname);
        end

        % Button pushed function: BeginImageDetectionButton_2
        function BeginImageDetectionButton_2Pushed(app, event)
                            img = app.UIAxes_3.UserData;

    % Check if UserData is empty — means no image has been imported yet
    if isempty(img)
        uialert(app.UIFigure, 'Please import an image first.', 'No Image');
        return;
    end
        end

        % Button pushed function: BeginImageDetectionButton_3
        function BeginImageDetectionButton_3Pushed(app, event)
            img = app.UIAxes_4.UserData;

            % Check if UserData is empty — means no image has been imported yet
            if isempty(img)
                uialert(app.UIFigure, 'Please import an image first.', 'No Image');
                return;
            end

            app. ResultEditField.Value = 'Loading...';
            app.ResultEditField.FontColor = [1 1 1]; % White
            drawnow; % Force UI to refresh immediately

            % Call defect detection function and display result
            [result, defect] = detectNitrileGloveDefect(img, app.UIAxes_4);

            % Update UI
            if strcmp(result, 'DEFECTIVE')
                app.ResultEditField.FontColor = [1 0 0];   % Red for defective
            elseif strcmp(result, 'NON-DEFECTIVE')
                app.ResultEditField.FontColor = [0 1 0]; % Green for non-defective
            end
            app. ResultEditField.Value = result;
            app. DefectEditField.Value = defect;
        end

        % Button pushed function: BeginImageDetectionButton_4
        function BeginImageDetectionButton_4Pushed(app, event)
                            img = app.UIAxes_5.UserData;

    % Check if UserData is empty — means no image has been imported yet
    if isempty(img)
        uialert(app.UIFigure, 'Please import an image first.', 'No Image');
        return;
    end
        end

        % Button pushed function: ImportImageButton_3
        function ImportImageButton_3Pushed(app, event)
                            [filename, path] = uigetfile({'*.jpeg;*.jpg;*.png;*.bmp;*.tiff', ...
                                   'Image Files'}, 'Select Glove Image');
                            app.ResultEditField.Value = '';
                            app.DefectEditField.Value = '';
                            drawnow;

    if isequal(filename, 0)
        return;
    end


    % Build full path
    fullname = fullfile(path, filename);

    % Derive clean name (no path, no extension) and update the text field here
    [~, fName, ~] = fileparts(fullname);
    app.ImageFilenameEditField_3.Value = fName;

    % Now just load and display the image
    app.loadAndDisplayImage(app.UIAxes_4, fullname);
        end

        % Button pushed function: ImportImageButton_4
        function ImportImageButton_4Pushed(app, event)
                            [filename, path] = uigetfile({'*.jpeg;*.jpg;*.png;*.bmp;*.tiff', ...
                                   'Image Files'}, 'Select Glove Image');
    if isequal(filename, 0)
        return;
    end


    % Build full path
    fullname = fullfile(path, filename);

    % Derive clean name (no path, no extension) and update the text field here
    [~, fName, ~] = fileparts(fullname);
    app.ImageFilenameEditField_4.Value = fName;

    % Now just load and display the image
    app.loadAndDisplayImage(app.UIAxes_5, fullname);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.LatexGloveTab = uitab(app.TabGroup);
            app.LatexGloveTab.Title = 'Latex Glove';
            app.UIAxes_2 = uiaxes(app.LatexGloveTab);
            app.ImportImageButton = uibutton(app.LatexGloveTab, 'push');
            app.ImportImageButton.ButtonPushedFcn = createCallbackFcn(app, @ImportImageButtonPushed, true);
            app.ImportImageButton.Tooltip = {'Click this to import an Image from your local storage'};
            app.ImportImageButton.Text = 'Import Image';
            app.ImageFilenameEditFieldLabel = uilabel(app.LatexGloveTab);
            app.ImageFilenameEditFieldLabel.Text = 'Image Filename:';
            app.ImageFilenameEditField = uieditfield(app.LatexGloveTab, 'text');
            app.ImageFilenameEditField.Editable = 'off';
            app.ImageFilenameEditField.Tooltip = {'The Filename of your image will be shown here'};
            app.BeginImageDetectionButton = uibutton(app.LatexGloveTab, 'push');
            app.BeginImageDetectionButton.ButtonPushedFcn = createCallbackFcn(app, @BeginImageDetectionButtonPushed, true);
            app.BeginImageDetectionButton.Tooltip = {'Click this to start detecting defects on your glove image'};
            app.BeginImageDetectionButton.Text = 'Begin Image Detection';
            app.ResultEditField_2Label = uilabel(app.LatexGloveTab);
            app.ResultEditField_2Label.Text = 'Result:';
            app.ResultEditField_2 = uieditfield(app.LatexGloveTab, 'text');
            app.DefectEditField_2Label = uilabel(app.LatexGloveTab);
            app.DefectEditField_2Label.Text = 'Defect:';
            app.DefectEditField_2 = uieditfield(app.LatexGloveTab, 'text');
            app.CottonGloveTab = uitab(app.TabGroup);
            app.CottonGloveTab.Title = 'Cotton Glove';
            app.UIAxes_3 = uiaxes(app.CottonGloveTab);
            app.ImportImageButton_2 = uibutton(app.CottonGloveTab, 'push');
            app.ImportImageButton_2.ButtonPushedFcn = createCallbackFcn(app, @ImportImageButton_2Pushed, true);
            app.ImportImageButton_2.Tooltip = {'Click this to import an Image from your local storage'};
            app.ImportImageButton_2.Text = 'Import Image';
            app.ImageFilenameEditField_2Label = uilabel(app.CottonGloveTab);
            app.ImageFilenameEditField_2Label.Text = 'Image Filename:';
            app.ImageFilenameEditField_2 = uieditfield(app.CottonGloveTab, 'text');
            app.ImageFilenameEditField_2.Editable = 'off';
            app.ImageFilenameEditField_2.Tooltip = {'The Filename of your image will be shown here'};
            app.BeginImageDetectionButton_2 = uibutton(app.CottonGloveTab, 'push');
            app.BeginImageDetectionButton_2.ButtonPushedFcn = createCallbackFcn(app, @BeginImageDetectionButton_2Pushed, true);
            app.BeginImageDetectionButton_2.Tooltip = {'Click this to start detecting defects on your glove image'};
            app.BeginImageDetectionButton_2.Text = 'Begin Image Detection';
            app.NitrileGloveTab = uitab(app.TabGroup);
            app.NitrileGloveTab.Title = 'Nitrile Glove';
            app.UIAxes_4 = uiaxes(app.NitrileGloveTab);
            app.ImportImageButton_3 = uibutton(app.NitrileGloveTab, 'push');
            app.ImportImageButton_3.ButtonPushedFcn = createCallbackFcn(app, @ImportImageButton_3Pushed, true);
            app.ImportImageButton_3.Tooltip = {'Click this to import an Image from your local storage'};
            app.ImportImageButton_3.Text = 'Import Image';
            app.ImageFilenameEditField_3Label = uilabel(app.NitrileGloveTab);
            app.ImageFilenameEditField_3Label.Text = 'Image Filename:';
            app.ImageFilenameEditField_3 = uieditfield(app.NitrileGloveTab, 'text');
            app.ImageFilenameEditField_3.Editable = 'off';
            app.ImageFilenameEditField_3.Tooltip = {'The Filename of your image will be shown here'};
            app.BeginImageDetectionButton_3 = uibutton(app.NitrileGloveTab, 'push');
            app.BeginImageDetectionButton_3.ButtonPushedFcn = createCallbackFcn(app, @BeginImageDetectionButton_3Pushed, true);
            app.BeginImageDetectionButton_3.Tooltip = {'Click this to start detecting defects on your glove image'};
            app.BeginImageDetectionButton_3.Text = 'Begin Image Detection';
            app.ResultLabel = uilabel(app.NitrileGloveTab);
            app.ResultLabel.Text = 'Result:';
            app.ResultEditField = uieditfield(app.NitrileGloveTab, 'text');
            app.DefectEditFieldLabel = uilabel(app.NitrileGloveTab);
            app.DefectEditFieldLabel.Text = 'Defect:';
            app.DefectEditField = uieditfield(app.NitrileGloveTab, 'text');
            app.PVCNylonGloveTab = uitab(app.TabGroup);
            app.PVCNylonGloveTab.Title = 'PVC/Nylon Glove';
            app.UIAxes_5 = uiaxes(app.PVCNylonGloveTab);
            app.ImportImageButton_4 = uibutton(app.PVCNylonGloveTab, 'push');
            app.ImportImageButton_4.ButtonPushedFcn = createCallbackFcn(app, @ImportImageButton_4Pushed, true);
            app.ImportImageButton_4.Tooltip = {'Click this to import an Image from your local storage'};
            app.ImportImageButton_4.Text = 'Import Image';
            app.ImageFilenameEditField_4Label = uilabel(app.PVCNylonGloveTab);
            app.ImageFilenameEditField_4Label.Text = 'Image Filename:';
            app.ImageFilenameEditField_4 = uieditfield(app.PVCNylonGloveTab, 'text');
            app.ImageFilenameEditField_4.Editable = 'off';
            app.ImageFilenameEditField_4.Tooltip = {'The Filename of your image will be shown here'};
            app.BeginImageDetectionButton_4 = uibutton(app.PVCNylonGloveTab, 'push');
            app.BeginImageDetectionButton_4.ButtonPushedFcn = createCallbackFcn(app, @BeginImageDetectionButton_4Pushed, true);
            app.BeginImageDetectionButton_4.Tooltip = {'Click this to start detecting defects on your glove image'};
            app.BeginImageDetectionButton_4.Text = 'Begin Image Detection';
            app.Group13GlovesDefectDetectionSystemLabel = uilabel(app.UIFigure);
            app.Group13GlovesDefectDetectionSystemLabel.Text = 'Group 13 Gloves Defect Detection System';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = LatextGloveDetectionApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end